classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HlavniStrankaMenu               matlab.ui.container.Menu
        InfoMenu                        matlab.ui.container.Menu
        infoButton                      matlab.ui.control.Button
        PorovnatAlgoritmyCheckBox       matlab.ui.control.CheckBox
        ZobrazitGrafCheckBox            matlab.ui.control.CheckBox
        NahrajSouborLabel               matlab.ui.control.Label
        NahratSouborButton              matlab.ui.control.Button
        PorovnavanalgoritmuzobrazenigrafuvkladnisouboruuzivatelskvberradiciposloupnostiTICTOCLabel  matlab.ui.control.Label
        VasepoleEditField               matlab.ui.control.EditField
        VaepoleEditFieldLabel           matlab.ui.control.Label
        SerazenpoleLabel                matlab.ui.control.Label
        SerazenePole                    matlab.ui.control.Label
        TypradicihoalgoritmuDropDown    matlab.ui.control.DropDown
        TypadchoalgoritmuDropDownLabel  matlab.ui.control.Label
        RaditButton                     matlab.ui.control.Button
        GrafAlgoritmuAxes               matlab.ui.control.UIAxes
    end

    methods (Access = private)
        %% Buble sort algoritmus
        function buble=bubleSort(A)
            Y = A;
            for i = 1:length(Y)-1
                for j = 1:length(Y)-1
                    if Y(j) > Y(j+1)
                        Y([j,j+1]) = Y([j+1,j]);
                    end
                end
            end
            buble = Y;
        end
        %% Stalin sort algoritmus
        function stalin = staliSort(A)
            tempMax = A(1);
            result = zeros(size(A));
            result(1) = tempMax;
            index = 2;
            for i = 2:length(A)
                if tempMax <= A(i)
                    result(index) = A(i);
                    index = index+1;
                    tempMax = A(i);
                end
            end
            stalin = result;
        end 
        %% Heap sort algoritmus
        function heap = heapSort(heap)
            delkaPole = length(heap);
            for i = floor(delkaPole / 2):-1:1
                heap = heapify(heap, delkaPole, i);
            end

            for i = delkaPole:-1:2
                heap = swap(heap, 1, i);
                heap = heapify(heap, i - 1, 1);
            end
        end

        function heap = heapify(heap, delkaPole, i)
            nejvetsi = i;
            leva = 2 * i;
            prava = 2 * i + 1;

            if leva <= delkaPole && heap(leva) > heap(nejvetsi)
                nejvetsi = leva;
            end

            if prava <= delkaPole && heap(prava) > heap(nejvetsi)
                nejvetsi = prava;
            end

            if nejvetsi ~= i
                heap = swap(heap, i, nejvetsi);
                heap = heapify(heap, delkaPole, nejvetsi);
            end
        end
        function heap = swap(heap, index, nextIndex)
            heap([index nextIndex]) = heap([nextIndex index]);
        end
        %%
        function quick = quickSort
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RaditButton
        function RaditButtonPushed(app, event)
            typAlgoritmu = app.TypradicihoalgoritmuDropDown.Value;
            poleHodnot=str2num(app.VasepoleEditField.Value);
            grafValue = app.ZobrazitGrafCheckBox.Value;
            if grafValue == true
                app.GrafAlgoritmuAxes.Visible = "on";
                plot([0:100].^2, [0:100].^2);

            end

            if typAlgoritmu == 'Bublesort'
                app.SerazenePole.Text = num2str(bubleSort(poleHodnot));
            elseif typAlgoritmu == 'Stalinsort'
                app.SerazenePole.Text = num2str(staliSort(poleHodnot));
            elseif typAlgoritmu == 'Heapsort'
                app.SerazenePole.Text = num2str(heapSort(poleHodnot));
            elseif typAlgoritmu == 'Quicksort'
                app.SerazenePole.Text = num2str(quickSort(poleHodnot));
            end
            
        end

        % Menu selected function: InfoMenu
        function InfoMenuSelected(app, event)
            
        end

        % Button pushed function: NahratSouborButton
        function NahratSouborButtonPushed(app, event)
            
        end

        % Value changed function: ZobrazitGrafCheckBox
        function ZobrazitGrafCheckBoxValueChanged(app, event)
            value = app.ZobrazitGrafCheckBox.Value;
            
        end

        % Button pushed function: infoButton
        function infoButtonPushed(app, event)
            
        end

        % Menu selected function: HlavniStrankaMenu
        function HlavniStrankaMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HlavniStrankaMenu = uimenu(app.UIFigure);
            app.HlavniStrankaMenu.MenuSelectedFcn = createCallbackFcn(app, @HlavniStrankaMenuSelected, true);
            app.HlavniStrankaMenu.Text = 'Hlavní stránka';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoMenuSelected, true);
            app.InfoMenu.Text = 'Info';
            app.GrafAlgoritmuAxes = uiaxes(app.UIFigure);
            title(app.GrafAlgoritmuAxes, 'Graf Algoritmu')
            xlabel(app.GrafAlgoritmuAxes, 'X')
            ylabel(app.GrafAlgoritmuAxes, 'Y')
            zlabel(app.GrafAlgoritmuAxes, 'Z')
            app.GrafAlgoritmuAxes.Visible = 'off';
            app.RaditButton = uibutton(app.UIFigure, 'push');
            app.RaditButton.ButtonPushedFcn = createCallbackFcn(app, @RaditButtonPushed, true);
            app.RaditButton.Text = 'Řadit';
            app.TypadchoalgoritmuDropDownLabel = uilabel(app.UIFigure);
            app.TypadchoalgoritmuDropDownLabel.Text = 'Typ řadícího algoritmu';
            app.TypradicihoalgoritmuDropDown = uidropdown(app.UIFigure);
            app.TypradicihoalgoritmuDropDown.Items = {'Bublesort', 'Stalinsort', 'Heapsort', 'Quicksort'};
            app.TypradicihoalgoritmuDropDown.Value = 'Bublesort';
            app.SerazenePole = uilabel(app.UIFigure);
            app.SerazenePole.Text = '0';
            app.SerazenpoleLabel = uilabel(app.UIFigure);
            app.SerazenpoleLabel.Text = 'Seřazené pole';
            app.VaepoleEditFieldLabel = uilabel(app.UIFigure);
            app.VaepoleEditFieldLabel.Text = 'Vaše pole';
            app.VasepoleEditField = uieditfield(app.UIFigure, 'text');
            app.PorovnavanalgoritmuzobrazenigrafuvkladnisouboruuzivatelskvberradiciposloupnostiTICTOCLabel = uilabel(app.UIFigure);
            app.PorovnavanalgoritmuzobrazenigrafuvkladnisouboruuzivatelskvberradiciposloupnostiTICTOCLabel.Text = 'Porovnavaní algoritmu, zobrazeni grafu, vkladáni souboru, uzivatelský výber radici posloupnosti, TICTOC';
            app.NahratSouborButton = uibutton(app.UIFigure, 'push');
            app.NahratSouborButton.ButtonPushedFcn = createCallbackFcn(app, @NahratSouborButtonPushed, true);
            app.NahratSouborButton.Text = 'Nahrát soubor';
            app.NahrajSouborLabel = uilabel(app.UIFigure);
            app.NahrajSouborLabel.Text = 'Nahrajte soubor s daty';
            app.ZobrazitGrafCheckBox = uicheckbox(app.UIFigure);
            app.ZobrazitGrafCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZobrazitGrafCheckBoxValueChanged, true);
            app.ZobrazitGrafCheckBox.Text = 'Zobrazit graf';
            app.PorovnatAlgoritmyCheckBox = uicheckbox(app.UIFigure);
            app.PorovnatAlgoritmyCheckBox.Text = 'Porovnat Algoritmy';
            app.infoButton = uibutton(app.UIFigure, 'push');
            app.infoButton.ButtonPushedFcn = createCallbackFcn(app, @infoButtonPushed, true);
            app.infoButton.Text = 'i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end